classdef WinEMG_chanview_021425 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoFilterCheckBox            matlab.ui.control.CheckBox
        MattsLargeMatfilecombinedIntanfilesButton  matlab.ui.control.Button
        LoadIntanDataButton         matlab.ui.control.Button
        CheckuncheckallchannelsCheckBox  matlab.ui.control.CheckBox
        SelectchannelstoviewLabel   matlab.ui.control.Label
        CheckBox_16                 matlab.ui.control.CheckBox
        CheckBox_15                 matlab.ui.control.CheckBox
        CheckBox_14                 matlab.ui.control.CheckBox
        CheckBox_13                 matlab.ui.control.CheckBox
        CheckBox_12                 matlab.ui.control.CheckBox
        CheckBox_11                 matlab.ui.control.CheckBox
        CheckBox_10                 matlab.ui.control.CheckBox
        CheckBox_9                  matlab.ui.control.CheckBox
        CheckBox_8                  matlab.ui.control.CheckBox
        CheckBox_7                  matlab.ui.control.CheckBox
        CheckBox_6                  matlab.ui.control.CheckBox
        CheckBox_5                  matlab.ui.control.CheckBox
        CheckBox_4                  matlab.ui.control.CheckBox
        CheckBox_3                  matlab.ui.control.CheckBox
        CheckBox_2                  matlab.ui.control.CheckBox
        CheckBox_1                  matlab.ui.control.CheckBox
        ChOffsetuVEditField         matlab.ui.control.NumericEditField
        ChOffsetuVLabel             matlab.ui.control.Label
        EndtimesEditField           matlab.ui.control.NumericEditField
        EndtimesEditFieldLabel      matlab.ui.control.Label
        StarttimesEditField         matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel    matlab.ui.control.Label
        LoadOpenEphysDataButton     matlab.ui.control.Button
        GeneratePlotButton          matlab.ui.control.Button
        HighCutoffHzEditField       matlab.ui.control.NumericEditField
        HighCutoffHzEditFieldLabel  matlab.ui.control.Label
        LowCutoffHzEditField        matlab.ui.control.NumericEditField
        LowCutoffHzEditFieldLabel   matlab.ui.control.Label
        VisualizeMyomatrixEMGRecordingsAcrossChannelsLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % must also have the NPY function reader in the MATLAB directory
        self             % for the user input functioning to work properly
        sr               % sampling rate (Hz)
        MyomatrixData    % All data recorded from 16 channels (channels x time)
        time             % time vector from user selections (seconds)
        time_orig        % original time vector of trial (seconds)
        selectedChannels % User selected channels to plot (max = 16)
        lowCutoff        % band pass filter setting - low cut off in Hz
        highCutoff       % band pass filter setting - low cut off in Hz
        channelColors    % set custom colors for each channel
        chan_space       % The vertical spacing between channel recordings (uV)
        startTime        % User-specified start time
        endTime          % User-specified end time
        startIdx         % Adjusted start sample number after user-specified start time 
        endIdx           % Adjusted end sample number after user-specified start time
        startSample      % start sample in truncated data
        endSample        % end sample in truncated data
        samples          % vector of sample numbers
        end_adjust       % Enforcing that with Byte calculations the end sample does not exceed the max sample number
        samples_adjust   % adjusted sample numbers to reflect user time segment
        directory        % directory of OpenEphys .oebin file,
        load_case        % Identifies which data type is loaded (OpenEphys, Intan or Custom Matfile)
        total_duration   % Duration calculation for custom matfile format
        nofilter         % no filter selection - only plot raw traces
        file             % Intan or matlab file 
        path             % Intan or matlab file path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOpenEphysDataButton
        function LoadOpenEphysDataButtonPushed(app, event)
            
            app.load_case = 1;

            % Load OpenEphys Button - clear plot
            cla(app.UIAxes); %clear the plot
            app.UIAxes.YTick = []; % Clears the yticks
            title(app.UIAxes, '')  % Removes the title
            legend(app.UIAxes, 'off');        
            
            % Load the folder where the data is
            disp('Select folder that contains the .oebin file - typically recording1');
            app.directory = uigetdir(pwd, 'Select the folder containing OpenEphys .oebin data file');
            if app.directory == 0
                error('No folder selected.');
            end
            
            % Creating a struct called self which would contain all the information
            app.self.format = 'Binary';
            app.self.directory = app.directory;
            app.self.info = jsondecode(fileread(fullfile(app.self.directory, 'structure.oebin')));
            
            % Load metadata
            %app.directory = fullfile(app.self.directory, 'continuous', app.self.info.continuous.folder_name);
            app.directory = fullfile(app.self.directory, 'continuous', app.self.info.continuous(1).folder_name);

            stream = {};
            stream.metadata.sampleRate = app.self.info.continuous.sample_rate;
            stream.metadata.numChannels = app.self.info.continuous.num_channels;
            stream.timestamps = readNPY(fullfile(app.directory, 'timestamps.npy'));
            stream.sampleNumbers = readNPY(fullfile(app.directory, 'sample_numbers.npy'));
            % disp('Trial Sample Length')
            % length(stream.sampleNumbers)
            
            numChannels = stream.metadata.numChannels;  % Number of channels
            app.sr = stream.metadata.sampleRate;    % Sample rate
            %totalSamples = length(stream.sampleNumbers);
            
           % Create time vector based on start and end times
            app.time_orig = round(stream.timestamps,4); %this will capture the first time point in the trial which we need later on
            
            disp('Original Trial Start Time:');
            disp(app.time_orig(1));

            % disp('Original Trial Start Sample:');
            % disp(stream.sampleNumbers(1));

            disp('Original Trial End Time:');
            disp(app.time_orig(end));
            
            % disp('Original Trial End Sample:');
            % disp(stream.sampleNumbers(end));
            
            %adjust sample numbers to start at 1 for proper indexing
            if stream.sampleNumbers(1) ~= 1
                app.samples_adjust = stream.sampleNumbers - stream.sampleNumbers(1) + 1;
            end

            %disp('first 15 app.samples_adjust')
            %app.samples_adjust(1:15)

            % Calculate byte offsets
            bytesPerSample = 2; % int16 = 2 bytes
            bytesPerChannel = bytesPerSample * numChannels;
            
            % User specifies the time range to load
            disp('Please enter the start time (in seconds) for the section to load:');
            app.startTime = input('Start Time (s): ');
            disp(' '); % Empty line for spacing

            % Validate time range
            tolerance = 1e-4;
            if abs(app.startTime - app.time_orig(1)) < tolerance
                app.startTime = app.time_orig(1);
            end

            while app.startTime < app.time_orig(1)
               disp('Selected start time is less than the start of the trial.');
               disp(' '); % Empty line for spacing
               disp('Original Trial Start Time: ');
               disp(app.time_orig(1));
               disp(' '); % Empty line for spacing
               disp('Please enter the start time (in seconds) for the section to load:');
               app.startTime = input('Start Time (s): ');
               disp(' '); % Empty line for spacing
               %return;

            end

            disp('Please enter the end time (in seconds) for the section to load:');
            app.endTime = input('End Time (s): ');
            disp(' '); % Empty line for spacing

            % if close enough - ensure the end time aligns
            if abs(app.endTime - app.time_orig(end)) < tolerance
                app.endTime = app.time_orig(end);
            end

            while app.endTime > app.time_orig(end) || app.endTime < app.startTime
               disp('Selected end time is either less than specified start time or greater than the end of the trial.');
               disp(' '); % Empty line for spacing
               disp('Original Trial End Time:');
               disp(app.time_orig(end));
               disp(' '); % Empty line for spacing
               disp('Please enter the end time (in seconds) for the section to load:');
               app.endTime = input('End Time (s): ');
               disp(' '); % Empty line for spacing 
               %return;

            end


            % Convert time range to sample range
            app.startSample = round((app.startTime - app.time_orig(1)) * app.sr) + 1;
            app.endSample = round((app.endTime - app.time_orig(1)) * app.sr) + 1;

            % Validate sample range
            fileInfo = dir(fullfile(app.directory, 'continuous.dat'));
            fileSize = fileInfo.bytes;
            maxSamplesInFile = floor(fileSize / (numChannels * bytesPerSample));
            % disp('Calculated max samples in file with Bytes')
            % maxSamplesInFile

            app.end_adjust = 0; %default
            if app.endSample > maxSamplesInFile
                app.endSample = maxSamplesInFile;
                disp('End sample adjusted to maximum samples in file')
                app.end_adjust = 1;
            end

            if app.startSample < 1
                app.startSample = 1;
                disp('Start sample less than 1 updated to 1');
            end

            % Calculate the number of samples to load
            numSamplesToLoad = app.endSample - app.startSample + 1;

            % Validate anticipated mapping size
            startByte = (app.startSample - 1) * bytesPerChannel;
            anticipateSize = startByte + (numSamplesToLoad * bytesPerChannel);

            if anticipateSize > fileSize
                disp('Memory mapping exceeds file size.');
                return;
            end


            % Memory map the requested range
            Data = memmapfile(fullfile(app.directory, 'continuous.dat'), ...
                'Format', 'int16', ...
                'Offset', startByte, ...
                'Repeat', numSamplesToLoad * numChannels);

     
            % Access the raw data
            rawData = Data.Data;  % This will be a 1D array with the raw data
            
            % Reshape the data into channels x samples
            mappedData = reshape(rawData, numChannels, numSamplesToLoad);  % Reshape into a 24 x numSamples matrix
            
            % Extract the desired time window
            RawData = mappedData;  % This is now the data for the requested time range
            

            % Overwrite with user selections
            app.time = app.startTime:1/app.sr:app.endTime;
   
            %disp(['Time vector sample size ', num2str(t_l)])
            
            % Assign data to the app structure
            if size(RawData,1) ~= 16
                app.MyomatrixData = double(RawData(1:size(RawData,1),:));
            else
                app.MyomatrixData = double(RawData(1:16,:));
            end
            %app.MyomatrixData = double(RawData(1:16,:)); %ensure this is always the case
            %app.MyomatrixData = size(RawData,1);
          
            disp('Data successfully loaded for the specified time range');
   
            % create sample vector
            %app.samples = 1:1:length(app.time); % number of samples in trial time window 
            %disp('first 15 app.samples')
            %app.samples(1:15)


            % Set defaults
            app.chan_space = 1400; %uV default
            app.ChOffsetuVEditField.Value = app.chan_space; %default on GUI
            app.lowCutoff = 30; %default
            app.LowCutoffHzEditField.Value = app.lowCutoff;
            app.highCutoff = 10000; %default
            app.HighCutoffHzEditField.Value = app.highCutoff;
            app.selectedChannels = []; %default

            % Adjust the numeric field defaults
            app.StarttimesEditField.Value = round(app.startTime,2);
            app.EndtimesEditField.Value = round(app.endTime,2);


            % Reset ensure checkbox values unselected
            app.CheckBox_1.Value = false;
            app.CheckBox_2.Value = false;
            app.CheckBox_3.Value = false;
            app.CheckBox_4.Value = false;
            app.CheckBox_5.Value = false;
            app.CheckBox_6.Value = false;
            app.CheckBox_7.Value = false;
            app.CheckBox_8.Value = false;
            app.CheckBox_9.Value = false;
            app.CheckBox_10.Value = false;
            app.CheckBox_11.Value = false;
            app.CheckBox_12.Value = false;
            app.CheckBox_13.Value = false;
            app.CheckBox_14.Value = false;
            app.CheckBox_15.Value = false;
            app.CheckBox_16.Value = false;

            app.nofilter = 0; %default no flter unless checked by user

        end

        % Button pushed function: LoadIntanDataButton
        function LoadIntanDataButtonPushed(app, event)
            
            app.load_case = 2;

            % Load Intan Button - clear plot
            cla(app.UIAxes); %clear the plot
            app.UIAxes.YTick = []; % Clears the yticks
            legend(app.UIAxes, 'off'); 
            title(app.UIAxes, '')  % Removes the title
            

            [app.file,app.path,app.MyomatrixData, app.time, app.sr]= read_Intan_RHD2000_file_tsw;

            disp('Data successfully loaded');

            disp('Trial Start Time (s):');
            disp(app.time(1));

            disp('Trial End Time (s):');
            disp(app.time(end));

            app.startTime = app.time(1);
            app.endTime = app.time(end);

            app.time_orig = app.time;

            % Convert time range to sample range
            app.startSample = round((app.startTime - app.time_orig(1)) * app.sr) + 1;
            app.endSample = round((app.endTime - app.time_orig(1)) * app.sr) + 1;

  
            % Set defaults
            app.chan_space = 1400; %uV default
            app.ChOffsetuVEditField.Value = app.chan_space; %default on GUI
            app.lowCutoff = 30; %default
            app.LowCutoffHzEditField.Value = app.lowCutoff;
            app.highCutoff = 10000; %default
            app.HighCutoffHzEditField.Value = app.highCutoff;
            app.selectedChannels = []; %default

            % Adjust the numeric field defaults
            app.StarttimesEditField.Value = app.time(1);
            app.EndtimesEditField.Value = app.time(end);
         
            app.end_adjust = 0; %default - not time truncation

            % Reset ensure checkbox values unselected
            app.CheckBox_1.Value = false;
            app.CheckBox_2.Value = false;
            app.CheckBox_3.Value = false;
            app.CheckBox_4.Value = false;
            app.CheckBox_5.Value = false;
            app.CheckBox_6.Value = false;
            app.CheckBox_7.Value = false;
            app.CheckBox_8.Value = false;
            app.CheckBox_9.Value = false;
            app.CheckBox_10.Value = false;
            app.CheckBox_11.Value = false;
            app.CheckBox_12.Value = false;
            app.CheckBox_13.Value = false;
            app.CheckBox_14.Value = false;
            app.CheckBox_15.Value = false;
            app.CheckBox_16.Value = false;

            app.nofilter = 0; %default no flter unless checked by user

        end

        % Button pushed function: MattsLargeMatfilecombinedIntanfilesButton
        function MattsLargeMatfilecombinedIntanfilesButtonPushed(app, event)
        
        app.load_case = 3;
        cla(app.UIAxes); %clear the plot
        app.UIAxes.YTick = []; % Clears the yticks
        legend(app.UIAxes, 'off');  
        title(app.UIAxes, '')  % Removes the title

        % Ask user to select the .mat file
        [app.file, app.path] = uigetfile('*.mat', 'Select the MAT-file');
        if isequal(app.file, 0)
            disp('User cancelled file selection.');
            return; % Exit if no file is selected
        end
        
        % Load file metadata without fully loading the data
        matFilePath = fullfile(app.path, app.file);
        matObj = matfile(matFilePath); % Use matfile for memory efficiency
        
        % Get size of the variable EMG_Cleaned
        varInfo = whos(matObj, 'EMG_Cleaned');
        if isempty(varInfo)
            error('Variable "EMG_Cleaned" not found in the selected file.');
        end
        
        [~, num_samples] = size(matObj, 'EMG_Cleaned'); % Get data dimensions

        app.sr = input('Enter the sampling rate: ' );
       
        
        % Compute total duration in seconds
        app.total_duration = num_samples / app.sr;
        disp(['Total recording duration: ', num2str(app.total_duration), ' seconds']);
        
        % Ask user for start and end time, ensuring they are within valid range
        app.startTime = input(['Enter start time in seconds (0 : ', num2str(app.total_duration), '): ']);

         while app.startTime < 0
               disp('Selected start time is less than the start of the trial.');
               disp(' '); % Empty line for spacing
               disp('Original Trial Start Time: ');
               disp(app.time_orig(1));
               disp(' '); % Empty line for spacing
               disp('Please enter the start time (in seconds) for the section to load:');
               app.startTime = input('Start Time (s): ');
               disp(' '); % Empty line for spacing
               %return;

          end

        app.endTime = input(['Enter end time in seconds (', num2str(app.startTime), ' : ', num2str(app.total_duration), '): ']);

            while app.endTime > app.total_duration || app.endTime < app.startTime
               disp('Selected end time is either less than specified start time or greater than the end of the trial.');
               disp(' '); % Empty line for spacing
               disp('Original Trial End Time:');
               disp(app.total_duration);
               disp(' '); % Empty line for spacing
               disp('Please enter the end time (in seconds) for the section to load:');
               app.endTime = input('End Time (s): ');
               disp(' '); % Empty line for spacing 
               %return;

            end


        % Validate user input
        if app.startTime < 0 || app.endTime > app.total_duration || app.startTime >= app.endTime
            error('Invalid time range selected.');
        end
        

        % Convert time to sample indices
        app.startSample = round(app.startTime * app.sr) + 1; % Convert to sample index
        app.endSample = round(app.endTime * app.sr);
        
        % Load only the necessary segment
        app.MyomatrixData = matObj.EMG_Cleaned(:, app.startSample:app.endSample);
        
        % Create time vector for plotting
        app.time = linspace(app.startTime, app.endTime, size(app.MyomatrixData, 2)); % Time axis in seconds


        app.time_orig = app.time;

        % Convert time range to sample range
        app.startSample = round((app.startTime - app.time_orig(1)) * app.sr) + 1;
        app.endSample = round((app.endTime - app.time_orig(1)) * app.sr) + 1;


        % Set defaults
        app.chan_space = 1400; %uV default
        app.ChOffsetuVEditField.Value = app.chan_space; %default on GUI
        app.lowCutoff = 30; %default
        app.LowCutoffHzEditField.Value = app.lowCutoff;
        app.highCutoff = 10000; %default
        app.HighCutoffHzEditField.Value = app.highCutoff;
        app.selectedChannels = []; %default

        % Adjust the numeric field defaults
        app.StarttimesEditField.Value = app.time(1);
        app.EndtimesEditField.Value = app.time(end);
     
        app.end_adjust = 0; %default - not time truncation

        % Reset ensure checkbox values unselected
        app.CheckBox_1.Value = false;
        app.CheckBox_2.Value = false;
        app.CheckBox_3.Value = false;
        app.CheckBox_4.Value = false;
        app.CheckBox_5.Value = false;
        app.CheckBox_6.Value = false;
        app.CheckBox_7.Value = false;
        app.CheckBox_8.Value = false;
        app.CheckBox_9.Value = false;
        app.CheckBox_10.Value = false;
        app.CheckBox_11.Value = false;
        app.CheckBox_12.Value = false;
        app.CheckBox_13.Value = false;
        app.CheckBox_14.Value = false;
        app.CheckBox_15.Value = false;
        app.CheckBox_16.Value = false;

        app.nofilter = 0; %default no flter unless checked by user

        disp('Data successfully loaded for the specified time range');

        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
                       
            % OpenEphys Button
            cla(app.UIAxes); %clear the plot
            app.UIAxes.YTick = []; % Clears the yticks
            legend(app.UIAxes, 'off');

            if app.startTime < app.time(1)
            uialert(app.UIFigure, 'Enter a valid start time', 'Error');
            disp(['Trial start time is ', num2str(app.time(1))])
                return;
            end


            if app.endTime > app.time(end)
            uialert(app.UIFigure, 'Enter a valid end time', 'Error');
            disp(['Trial end time is ', num2str(app.time(end))])
                return;
            end


            % Validate cutoff frequencies
            if isempty(app.lowCutoff) || isempty(app.highCutoff) || app.lowCutoff >= app.highCutoff
                uialert(app.UIFigure, 'Invalid cutoff frequencies. Ensure Low < High.', 'Error');
                return;
            end


            if app.highCutoff > app.sr/2
                uialert(app.UIFigure, 'Cut off exceeds half the sampling rate!', 'Error');
                return;
            end

            if app.end_adjust == 0
            % Convert start and end times to sample indices
                app.startIdx = (round((app.startTime-app.time_orig(1))*app.sr)+1)-app.startSample+1; % This is the start sample in truncated data
                %app.startIdx
                app.endIdx = (round((app.endTime-app.time_orig(1))*app.sr)+1)-app.startSample+1;  % Convert end time to index
                %app.endIdx

            elseif app.end_adjust == 1
                app.startIdx = (round((app.startTime-app.time_orig(1))*app.sr)+1)-app.startSample+1; % This is the start sample in truncated data
                %app.startIdx
                app.endIdx = app.samples_adjust(end);  % Convert end time to index
                %app.endIdx
            end


            % Define a color map for each channel (predefined set of colors)
            app.channelColors = [
                0, 0, 1;         % Blue 1
                1, 0, 0;         % Red 2
                0, 1, 1;         % Cyan 3
                1, 0, 1;         % Magenta 4
                0.5, 0, 0;       % Maroon 5
                0.5, 1, 0.5;     % Light Green 6
                0, 0, 0;         % Black 7
                0.8, 0.6, 0.9;   % Light Purple 8
                0.5, 0.5, 0;     % Olive Green 9
                1, 0.5, 0.5;     % Light Pink 10
                1, 0.5, 0;       % Orange 11
                0.3, 0, 0.5;     % Dark Indigo 12
                0, 0.5, 0;       % Dark Green 13
                0, 0.5, 1;       % Light Blue 14
                1, 0, 0.5;       % Dark Pink 15
                0.5, 0.5, 1;     % Light Indigo 16
            ];
            
            % Extracting Title
            if app.load_case == 1; %OpenEphys
                folderPath = app.directory;
                
                % Split the directory path by the file separator
                folderParts = strsplit(folderPath, filesep);
                
                % Find the index of "Record Node 101" in the directory parts
                recordNodeIndex = find(contains(folderParts, 'Record Node 101'));
                
                % Extract the folder name before "Record Node 101" (if it exists)
                if ~isempty(recordNodeIndex) && recordNodeIndex > 1
                    folderBeforeRecordNode = folderParts{recordNodeIndex - 1};
                else
                    folderBeforeRecordNode = '';  % If no valid folder found before "Record Node 101"
                end
                
                % Replace underscores with spaces in the folder name
                folderBeforeRecordNode = strrep(folderBeforeRecordNode, '_', ' ');
                file_name = folderBeforeRecordNode;

            else
                folderBeforeRecordNode =  'Data title not found';
                file_name = folderBeforeRecordNode;

            end


           if app.load_case ==2; %Intan
               parts = split(app.file, '.'); % Split at the dot
               file_name = parts{1}; % Get the first part
               % Replace underscores with spaces
               file_name = join(split(file_name, '_'), ' ');

           end


            if app.load_case == 3; %Matt's Matfile
               app.startIdx = 1;        
               app.endIdx = length(app.time);
               parts = split(app.file, '.'); % Split at the dot
               file_name = parts{1}; % Get the first part
               % Replace underscores with spaces
               file_name = join(split(file_name, '_'), ' ');

            end


             disp('Working on your channel visualization now'); 

            plotFlag = 0;
            % Plot selected channels with a persistent color
            for i = 1:length(app.selectedChannels);

                %channelIndex = str2double(app.selectedChannels{i});
                channelIndex = app.selectedChannels(i);               
 
                A = app.MyomatrixData(channelIndex, :);  % Select the channel data slice unfiltered

                %disp('Channel data:');
                %display(A)
                if app.nofilter == 0 % filter
                    A_bp = bandpass(A, [app.lowCutoff, app.highCutoff], app.sr);  % Apply bandpass filter
                    inputData_wis = A_bp;
                    %disp(inputData)

                elseif app.nofilter == 1 % raw data no filter
                   inputData_wis = A;
                   %disp(inputData)
                    % do nothing
                end

                % Add WIS information (Matt Williams)
                
                [pos,neg,WIS] = FPT_triangleThreshold(inputData_wis,plotFlag);
                 %disp(pos,neg)

                % Get the color for the current channel from the predefined color list
                channelColor = app.channelColors(channelIndex, :);

                % Plot the data with an offset and the selected color
                plot(app.UIAxes,app.time(app.startIdx:app.endIdx), inputData_wis(app.startIdx:app.endIdx) + (app.chan_space * i), 'LineWidth',1, 'Color', channelColor, 'DisplayName', sprintf('Channel %d', channelIndex));
                
                hold(app.UIAxes, 'on');

                y_offset = app.chan_space * i;
                annotation_text = sprintf('WIS: %.1f', WIS);
    
                % Positioning the text slightly to the right of the end of the plot
                text(app.UIAxes,app.time(end) + 0.5, y_offset, annotation_text, 'Color', channelColor,'FontSize', 14, 'FontWeight', 'bold', 'VerticalAlignment', 'middle');
                hold(app.UIAxes, 'on');

            end

            % Use this folder name as the title for the plot
            title(app.UIAxes, file_name,'Color','blue','FontSize',18);

            % Adjust plot labels depending on selection to ensure no jumbling of text

            if app.chan_space > 30
                % Set y-ticks and labels
                yticks(app.UIAxes, app.chan_space * (1:length(app.selectedChannels)));  % Set the y-ticks to be at the offsets
                yticklabels(app.UIAxes, arrayfun(@(x) sprintf('Ch%d', x), app.selectedChannels, 'UniformOutput', false));  % Label each tick with the channel number
                yticklabels(app.UIAxes, app.selectedChannels);               

                legend(app.UIAxes, 'show');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Channel #');
                hold(app.UIAxes, 'off');

            elseif app.chan_space == 0   ||  app.chan_space < 30     
                % no y labels or ticks as the offset is 0  to 30 and would
                % be jumbled

                legend(app.UIAxes, 'show');
                xlabel(app.UIAxes, 'Time (s)');
                %ylabel(app.UIAxes6, 'Channel #');
                hold(app.UIAxes, 'off');

            else

                uialert(app.UIFigure, 'Please enter a value zero or above', 'Error');
                return; 

            end

        end

        % Value changed function: LowCutoffHzEditField
        function LowCutoffHzEditFieldValueChanged(app, event)
            app.lowCutoff  = app.LowCutoffHzEditField.Value; % Low cutoff frequency 
             
        end

        % Value changed function: HighCutoffHzEditField
        function HighCutoffHzEditFieldValueChanged(app, event)
            app.highCutoff = app.HighCutoffHzEditField.Value; % High cutoff frequency 
                       
        end

        % Value changed function: ChOffsetuVEditField
        function ChOffsetuVEditFieldValueChanged(app, event)
            app.chan_space = app.ChOffsetuVEditField.Value;
            
        end

        % Value changed function: StarttimesEditField
        function StarttimesEditFieldValueChanged(app, event)
            app.startTime = app.StarttimesEditField.Value;
            
        end

        % Value changed function: EndtimesEditField
        function EndtimesEditFieldValueChanged(app, event)
            app.endTime = app.EndtimesEditField.Value;
            
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            
            %value = app.CheckBox_1.Value;
            
            if app.CheckBox_1.Value
                % Add the checkbox index (1 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 1];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 1);
            end

            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            %value = app.CheckBox_2.Value;
            if app.CheckBox_2.Value
                % Add the checkbox index (2 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 2];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 2);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            %value = app.CheckBox_3.Value;
            if app.CheckBox_3.Value
                % Add the checkbox index (3 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 3];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 3);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            %value = app.CheckBox_4.Value;
            if app.CheckBox_4.Value
                % Add the checkbox index (4 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 4];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 4);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            %value = app.CheckBox_5.Value;
            if app.CheckBox_5.Value
                % Add the checkbox index (5 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 5];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 5);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            %value = app.CheckBox_6.Value;
            if app.CheckBox_6.Value
                % Add the checkbox index (6 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 6];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 6);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            %value = app.CheckBox_7.Value;
            if app.CheckBox_7.Value
                % Add the checkbox index (7 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 7];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 7);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            %value = app.CheckBox_8.Value;
            if app.CheckBox_8.Value
                % Add the checkbox index (8 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 8];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 8);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            %value = app.CheckBox_9.Value;
            if app.CheckBox_9.Value
                % Add the checkbox index (9 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 9];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 9);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            %value = app.CheckBox_10.Value;
            if app.CheckBox_10.Value
                % Add the checkbox index (10 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 10];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 10);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            %value = app.CheckBox_11.Value;
            if app.CheckBox_11.Value
                % Add the checkbox index (11 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 11];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 11);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            %value = app.CheckBox_12.Value;
            if app.CheckBox_12.Value
                % Add the checkbox index (12 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 12];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 12);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            %value = app.CheckBox_13.Value;
            if app.CheckBox_13.Value
                % Add the checkbox index (13 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 13];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 13);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            %value = app.CheckBox_14.Value;
            if app.CheckBox_14.Value
                % Add the checkbox index (14 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 14];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 14);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            %value = app.CheckBox_15.Value;
            if app.CheckBox_15.Value
                % Add the checkbox index (15 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 15];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 15);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
            %value = app.CheckBox_16.Value;
            if app.CheckBox_16.Value
                % Add the checkbox index (16 in this case) to the array
                app.selectedChannels = [app.selectedChannels, 16];
            else
                % Remove the index from the array if unchecked
                app.selectedChannels = setdiff(app.selectedChannels, 16);
            end
            disp('Selected Channels:');
            disp(app.selectedChannels);
            
        end

        % Value changed function: CheckuncheckallchannelsCheckBox
        function CheckuncheckallchannelsCheckBoxValueChanged(app, event)
            %value = app.CheckuncheckallchannelsCheckBox.Value;
            
           if app.CheckuncheckallchannelsCheckBox.Value
                app.CheckBox_1.Value = true;
                app.CheckBox_2.Value = true;
                app.CheckBox_3.Value = true;
                app.CheckBox_4.Value = true;
                app.CheckBox_5.Value = true;
                app.CheckBox_6.Value = true;
                app.CheckBox_7.Value = true;
                app.CheckBox_8.Value = true;
                app.CheckBox_9.Value = true;
                app.CheckBox_10.Value = true;
                app.CheckBox_11.Value = true;
                app.CheckBox_12.Value = true;
                app.CheckBox_13.Value = true;
                app.CheckBox_14.Value = true;
                app.CheckBox_15.Value = true;
                app.CheckBox_16.Value = true;
                app.selectedChannels = 1:16;

           else
                app.CheckBox_1.Value = false;
                app.CheckBox_2.Value = false;
                app.CheckBox_3.Value = false;
                app.CheckBox_4.Value = false;
                app.CheckBox_5.Value = false;
                app.CheckBox_6.Value = false;
                app.CheckBox_7.Value = false;
                app.CheckBox_8.Value = false;
                app.CheckBox_9.Value = false;
                app.CheckBox_10.Value = false;
                app.CheckBox_11.Value = false;
                app.CheckBox_12.Value = false;
                app.CheckBox_13.Value = false;
                app.CheckBox_14.Value = false;
                app.CheckBox_15.Value = false;
                app.CheckBox_16.Value = false;
                app.selectedChannels = [];
                 
           end


        end

        % Value changed function: NoFilterCheckBox
        function NoFilterCheckBoxValueChanged(app, event)
            if app.NoFilterCheckBox.Value;
      
               app.LowCutoffHzEditField.Value = 0;
               app.HighCutoffHzEditField.Value = 0;
               app.nofilter = 1; %true

            else

               app.nofilter = 0; %filter

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VisualizeMyomatrixEMGRecordingsAcrossChannelsLabel = uilabel(app.UIFigure);
            app.VisualizeMyomatrixEMGRecordingsAcrossChannelsLabel.Text = 'Visualize Myomatrix EMG Recordings Across Channels';
            app.LowCutoffHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowCutoffHzEditFieldLabel.Text = 'Low Cutoff (Hz)';
            app.LowCutoffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowCutoffHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowCutoffHzEditFieldValueChanged, true);
            app.HighCutoffHzEditFieldLabel = uilabel(app.UIFigure);
            app.HighCutoffHzEditFieldLabel.Text = 'High Cutoff (Hz)';
            app.HighCutoffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighCutoffHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighCutoffHzEditFieldValueChanged, true);
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.LoadOpenEphysDataButton = uibutton(app.UIFigure, 'push');
            app.LoadOpenEphysDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOpenEphysDataButtonPushed, true);
            app.LoadOpenEphysDataButton.Text = 'Load OpenEphys Data';
            app.StarttimesEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimesEditFieldLabel.Text = 'Start time (s)';
            app.StarttimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimesEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimesEditFieldValueChanged, true);
            app.EndtimesEditFieldLabel = uilabel(app.UIFigure);
            app.EndtimesEditFieldLabel.Text = 'End time (s)';
            app.EndtimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimesEditFieldValueChanged, true);
            app.ChOffsetuVLabel = uilabel(app.UIFigure);
            app.ChOffsetuVLabel.Text = 'Ch. Offset (uV)';
            app.ChOffsetuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChOffsetuVEditField.ValueChangedFcn = createCallbackFcn(app, @ChOffsetuVEditFieldValueChanged, true);
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '10';
            app.CheckBox_11 = uicheckbox(app.UIFigure);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '11';
            app.CheckBox_12 = uicheckbox(app.UIFigure);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '12';
            app.CheckBox_13 = uicheckbox(app.UIFigure);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '13';
            app.CheckBox_14 = uicheckbox(app.UIFigure);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '14';
            app.CheckBox_15 = uicheckbox(app.UIFigure);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '15';
            app.CheckBox_16 = uicheckbox(app.UIFigure);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Text = '16';
            app.SelectchannelstoviewLabel = uilabel(app.UIFigure);
            app.SelectchannelstoviewLabel.Text = {'Select channels'; ' to view'};
            app.CheckuncheckallchannelsCheckBox = uicheckbox(app.UIFigure);
            app.CheckuncheckallchannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckuncheckallchannelsCheckBoxValueChanged, true);
            app.CheckuncheckallchannelsCheckBox.Text = ' Check/uncheck all channels';
            app.LoadIntanDataButton = uibutton(app.UIFigure, 'push');
            app.LoadIntanDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntanDataButtonPushed, true);
            app.LoadIntanDataButton.Text = 'Load Intan Data';
            app.MattsLargeMatfilecombinedIntanfilesButton = uibutton(app.UIFigure, 'push');
            app.MattsLargeMatfilecombinedIntanfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MattsLargeMatfilecombinedIntanfilesButtonPushed, true);
            app.MattsLargeMatfilecombinedIntanfilesButton.Text = {'Matt''s Large Matfile '; '(combined Intan files)'};
            app.NoFilterCheckBox = uicheckbox(app.UIFigure);
            app.NoFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoFilterCheckBoxValueChanged, true);
            app.NoFilterCheckBox.Text = 'No Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WinEMG_chanview_021425

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end